classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ClickMeButton      matlab.ui.control.Button
        NumEditFieldLabel  matlab.ui.control.Label
        NumEditField       matlab.ui.control.NumericEditField
        graph              matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: ClickMeButton
        function ClickMeButtonPushed(app, event)
            %Read in raw data to plot
            [~,~,raw_test] = xlsread('test.xls',2);
            
            %Read in cmapaign to lot# table
            [~,~,campaign_lot_table] = xlsread('campaign_batch_key.xls',1);
            
            %Extract UL and LL bounds from user
            prompt = {'Please Enter Upper Spec Limit','Please Enter Lower Spec Limit'};
            spec_limits = char(inputdlg(prompt,'Spec Limit Specification'));
            
            %Check UL and LL 
            
            %Store (Campaign,Batch#) as Key:Value pair in Map
            keySet = campaign_lot_table(:,1);
            valueSet = campaign_lot_table(:,2);
            M = containers.Map(keySet,valueSet);
            
            %Extract Indices of Campaign Changes 
            all_campaign_names = raw_test(2:end,1);
            campaign_change_index = [];
            for i = 1: size(all_campaign_names,1)-1
                if strcmp(all_campaign_names{i,:},all_campaign_names{i+1,:}) == 0
                    campaign_change_index(end+1) = i;
                end
            end
            %This is to account for the header
            campaign_change_index = campaign_change_index + 1;
            
            %Extracts a attribute column(w/ headers) to test
            attribute_column_test = raw_test(:,1:2);
            
            %Extract Attribute Name
            attribute_name = attribute_column_test(1,2)
            
            %Extract and convert campaign Names to Batch#
            unique_campaign_name = unique(all_campaign_names);
            size(unique_campaign_name)
            unique_batch_number = cell(size(unique_campaign_name,1),1);
            for i = 1:size(unique_campaign_name)
                unique_batch_number{i} = M(unique_campaign_name{i});
            end
            
            
            %Split column Data by its campaigns into cell array
            start_index = 2;
            
            %Set Figure and variables
            figure(1)
            hold on;
            x = cell(2,1);
            g = cell(2,1);
           
            %Set x and y axis label
            app.graph.YLabel.String = (attribute_name{1}(2:end));
            app.graph.XLabel.String = 'Lot#';
            app.graph.XTickLabelRotation = 45;
            
            %Intialize Counter 
            counter = 1;
            while counter <= size(campaign_change_index,2)
                extracted_cell_array = raw_test(start_index:campaign_change_index(counter),2);
                extracted_array = cell2mat(extracted_cell_array);
                x_axis = zeros(size(extracted_array,1),1);
                x_axis(:) = 1;
                num = (counter-.1) + rand(size(x_axis,1),1)*((counter+.1)-(counter-.1));
                x_axis_jitter = num.*x_axis;
                y =  plot(app.graph,x_axis_jitter,extracted_array,'.','Color','k','MarkerSize', 10);
                y.LineStyle = 'none';
                
                x{end+1} = extracted_array;
                g{end+1} = [counter*ones(size(extracted_array))];
                start_index = campaign_change_index + 1;
                counter = counter + 1;
            end
            extracted_cell_array = raw_test(start_index:end,2);
            extracted_array = cell2mat(extracted_cell_array);
            x_axis = zeros(size(extracted_array,1),1);
            x_axis(:) = 1;
            num = (counter-.1) + rand(size(x_axis,1),1)*((counter+.1)-(counter-.1));
            x_axis_jitter = num.*x_axis;
            y =  plot(app.graph,x_axis_jitter,extracted_array,'.','Color','k','MarkerSize', 10);
            y.LineStyle = 'none';
            
            x{end+1} = extracted_array;
            g{end+1} = [counter*ones(size(extracted_array))];
            
            
            %Plot Box Plots
            bx_1 = boxplot(app.graph,cell2mat(x(5)),'Color','k','Symbol','k.','whisker',100);
            set(bx_1,'LineWidth',2);
            
            bx = boxplot(app.graph,cell2mat(x),cell2mat(g),'Color','r','Symbol','k.','OutlierSize',10);
            set(bx,'LineWidth',2);
            set(findobj(gcf,'-regexp','Tag','\w*Whisker'),'LineStyle','-')
            set(gca,'XTickLabel',unique_batch_number)
            
            %Calculate and Plot Average Line
            complete_attribute_data = raw_test(2:end,2);
            avg  = mean(cell2mat(complete_attribute_data));
            x_avg_axis = linspace(0,10,100);
            y_avg = zeros(size(x_avg_axis,2),1);
            y_avg(:) = avg;
            g = plot(app.graph,x_avg_axis,y_avg,'--','Color','k');
            legend([g],{strcat('Avg = ',num2str(avg))},'Location','best');
            legend('boxoff')
            
            
            %Plot USL and LSL(Upper Spec Limit and Lower Spec Limit)
            UL  = str2double(spec_limits(1,:));
            LL = str2double(spec_limits(2,:));
            x_UL_axis = linspace(0,10,100);
            y_UL = zeros(size(x_UL_axis,2),1);
            y_UL(:) = UL;
            y_LL = zeros(size(x_UL_axis,2),1);
            y_LL(:) = LL;
            ul = plot(app.graph,x_UL_axis,y_UL,'-','Color','r');
            ll = plot(app.graph,x_UL_axis,y_LL,'-','Color','r');
            
            
            %Add Title
            title(attribute_name{1}(2:end))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ClickMeButton = uibutton(app.UIFigure, 'push');
            app.ClickMeButton.ButtonPushedFcn = createCallbackFcn(app, @ClickMeButtonPushed, true);
            app.ClickMeButton.Text = 'Click Me!';
            app.NumEditFieldLabel = uilabel(app.UIFigure);
            app.NumEditFieldLabel.Text = 'Num';
            app.NumEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumEditField.Editable = 'off';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Title');
            xlabel(app.graph, 'X');
            ylabel(app.graph, 'Y');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end